classdef Robot_Path_Planning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RobotPathPlanningVersion1Label  matlab.ui.control.Label
        ReportPanel                     matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        Best_CostEditField              matlab.ui.control.EditField
        Best_CostEditFieldLabel         matlab.ui.control.Label
        NFEEditField                    matlab.ui.control.EditField
        NFEEditFieldLabel               matlab.ui.control.Label
        ItEditField                     matlab.ui.control.EditField
        ItEditFieldLabel                matlab.ui.control.Label
        PSOParametersPanel              matlab.ui.container.Panel
        C2Slider                        matlab.ui.control.Slider
        C2SliderLabel                   matlab.ui.control.Label
        C1Slider                        matlab.ui.control.Slider
        C1SliderLabel                   matlab.ui.control.Label
        InertiaWeightDampingRatioEditField  matlab.ui.control.EditField
        InertiaWeightDampingRatioEditFieldLabel  matlab.ui.control.Label
        InertiaWeightEditField          matlab.ui.control.EditField
        InertiaWeightEditFieldLabel     matlab.ui.control.Label
        NumberofPopulationEditField     matlab.ui.control.EditField
        NumberofPopulationLabel         matlab.ui.control.Label
        MaximumIterationEditField       matlab.ui.control.EditField
        MaximumIterationLabel           matlab.ui.control.Label
        ChartcoordinatesandnumberofsplinesPanel  matlab.ui.container.Panel
        NumberofSplinesEditField        matlab.ui.control.EditField
        NumberofSplinesEditFieldLabel   matlab.ui.control.Label
        Y_maxEditField                  matlab.ui.control.EditField
        Y_maxEditFieldLabel             matlab.ui.control.Label
        X_maxEditField                  matlab.ui.control.EditField
        X_maxEditFieldLabel             matlab.ui.control.Label
        Y_minEditField                  matlab.ui.control.EditField
        Y_minEditFieldLabel             matlab.ui.control.Label
        X_minEditField                  matlab.ui.control.EditField
        X_minEditFieldLabel             matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        CreateStartEndandObstacleButton  matlab.ui.control.Button
        ObstaclecoordinatesPanel        matlab.ui.container.Panel
        RadiusEditField                 matlab.ui.control.EditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        Y_CenterEditField               matlab.ui.control.EditField
        Y_CenterEditFieldLabel          matlab.ui.control.Label
        X_CenterEditField               matlab.ui.control.EditField
        X_CenterEditFieldLabel          matlab.ui.control.Label
        StartandendcoordinatesPanel     matlab.ui.container.Panel
        Y_EndEditField                  matlab.ui.control.EditField
        Y_EndEditFieldLabel             matlab.ui.control.Label
        X_EndEditField                  matlab.ui.control.EditField
        X_EndEditFieldLabel             matlab.ui.control.Label
        Y_StartEditField                matlab.ui.control.EditField
        Y_StartEditFieldLabel           matlab.ui.control.Label
        X_StartEditField                matlab.ui.control.EditField
        X_StartEditFieldLabel           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        saveData = [] % save data
    end
    
    methods (Access = private)
        
        function model = CreateModel(app)

            xs = str2double(app.X_StartEditField.Value);
            ys = str2double(app.Y_StartEditField.Value);

            xt = str2double(app.X_EndEditField.Value);
            yt = str2double(app.Y_EndEditField.Value);

            xc = str2double(app.X_CenterEditField.Value);
            yc = str2double(app.Y_CenterEditField.Value);
            r = str2double(app.RadiusEditField.Value);

            n = str2double(app.NumberofSplinesEditField.Value);
            xmin = str2double(app.X_minEditField.Value);
            xmax = str2double(app.X_maxEditField.Value);
            ymin = str2double(app.Y_minEditField.Value);
            ymax = str2double(app.Y_maxEditField.Value);

            model.xs = xs;
            model.ys = ys;
            model.xt = xt;
            model.yt = yt;
            model.xc = xc;
            model.yc = yc;
            model.r = r;
            model.n = n;
            model.xmin = xmin;
            model.xmax = xmax;
            model.ymin = ymin;
            model.ymax = ymax;

        end

        function PlotSolution(app,Out,model)

            xs = model.xs;
            ys = model.ys;
            xt = model.xt;
            yt = model.yt;
            xc = model.xc;
            yc = model.yc;
            r = model.r;

            XS = Out.XS;
            YS = Out.YS;
            xx = Out.xx;
            yy = Out.yy;
            
            theta = linspace(0,2*pi,100);
            fill(app.UIAxes,xc+r*cos(theta),yc+r*sin(theta),'m')
            hold(app.UIAxes,'on')
            plot(app.UIAxes,xx,yy,'k','LineWidth',2)
            plot(app.UIAxes,XS,YS,'ro')
            plot(app.UIAxes,xs,ys,'bs','MarkerSize',16,'MarkerFaceColor','y')
            plot(app.UIAxes,xt,yt,'kp','MarkerSize',20,'MarkerFaceColor','g')
            hold(app.UIAxes,'off')
            drawnow;
        end

        function sol1 = CreateRandomSolution(~,model)

            n = model.n;
            xmin = model.xmin;
            xmax = model.xmax;
            ymin = model.ymin;
            ymax = model.ymax;

            sol1.x = unifrnd(xmin,xmax,1,n);
            sol1.y = unifrnd(ymin,ymax,1,n);

        end

        function [z, Out] = MyCost(app,sol1,model)

            global NFE;
            if isempty(NFE)
                NFE = 0;
            end
            NFE = NFE + 1;

            Out = app.ParseSolution(sol1,model);

            beta = 10;
            z = Out.L*(1+beta*Out.MeanViolation);

        end

        function sol2 = ParseSolution(~,sol1,model)

            x  =sol1.x;
            y = sol1.y;

            xs = model.xs;
            ys = model.ys;
            xt = model.xt;
            yt = model.yt;
            xc = model.xc;
            yc = model.yc;
            r = model.r;

            XS = [xs x xt];                  % XS = XSpline, xs = xstart, xt = xtarget
            YS = [ys y yt];                  % YS = YSpline, ys = ystart, yt = ytarget

            k = numel(XS);
            TS = linspace(0,1,k);

            tt = linspace(0,1,100);
            xx = spline(TS,XS,tt);
            yy = spline(TS,YS,tt);

            dx = diff(xx);
            dy = diff(yy);
            L = sum(sqrt(dx.^2 + dy.^2));

            d = sqrt((xx-xc).^2 + (yy-yc).^2);
            violation = max(1-d/r,0);
            MeanViolation = mean(violation);

            sol2.TS = TS;
            sol2.XS = XS;
            sol2.YS = YS;
            sol2.tt = tt;
            sol2.xx = xx;
            sol2.yy = yy;
            sol2.dx = dx;
            sol2.dy = dy;
            sol2.L = L;
            sol2.MeanViolation = MeanViolation;
            sol2.IsFeasible = (MeanViolation==0);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% Problem Definition
            global NFE;
            NFE=0;

            model = app.CreateModel();

            CostFunction = @(x) app.MyCost(x,model);                % Cost Function
            nVar = model.n;                                     % Number of Decision Variables
            VarSize = [1 nVar];                                 % Size of Decision Variables Matrix
            VarMin.x = model.xmin;                              % Lower Bound of Variables
            VarMax.x = model.xmax;                              % Upper Bound of Variables
            VarMin.y = model.ymin;                              % Lower Bound of Variables
            VarMax.y = model.ymax;                              % Upper Bound of Variables

            %%  PSO Parameters
            MaxIt = str2double(app.MaximumIterationEditField.Value) ;                               % Maximum Number of Iteration
            nPop = str2double(app.NumberofPopulationEditField.Value);                                 % Population Size (Swarm Size)
            w = str2double(app.InertiaWeightEditField.Value);                                      % Inertia Weight
            wdamp = str2double(app.InertiaWeightDampingRatioEditField.Value);                               % Inertia Weight Damping Ratio
            c1 = app.C1Slider.Value;                                     % Personal Learning Coefficient
            c2 = app.C2Slider.Value;                                     % Global Learning Coefficient

            % Constriction Coefficient
            % phi1 = 2.05;
            % phi2 = 2.05;
            % phi = phi1+phi2;
            % chi = 2/(phi-2+sqrt(phi^2-4*phi));
            % w = chi;                                  % Inertia Weight
            % wdamp = 1;                                % Inertia Weight Damping Ratio
            % c1 = chi*phi1;                            % Personal Learning Coefficient
            % c2 = chi*phi2;                            % Global Learning Coefficient

            % Velocity Limits
            alpha = 0.1;
            VelMax.x = alpha*(VarMax.x - VarMin.x);
            VelMin.x = -VelMax.x;
            VelMax.y = alpha*(VarMax.y - VarMin.y);
            VelMin.y = -VelMax.y;

            %% Initialization
            empty_particle.Position = [];
            empty_particle.Cost = [];
            empty_particle.Out = [];
            empty_particle.Velocity = [];
            empty_particle.Best.Position = [];
            empty_particle.Best.Cost = [];

            particle = repmat(empty_particle,nPop,1);
            GlobalBest.Cost = inf;

            for i=1:nPop

                % Initialize Position
                particle(i).Position = app.CreateRandomSolution(model);
                % Initialize Velocity
                particle(i).Velocity.x = zeros(VarSize);
                particle(i).Velocity.y = zeros(VarSize);
                % Evaluation
                [particle(i).Cost ,particle(i).Out] = CostFunction(particle(i).Position);

                % Update Personal Best
                particle(i).Best.Position = particle(i).Position;
                particle(i).Best.Cost = particle(i).Cost;
                particle(i).Best.Out = particle(i).Out;

                % Update Global Best
                if particle(i).Best.Cost < GlobalBest.Cost

                    %GlobalBest.Position = particle(i).Best.Position;
                    %GlobalBest.Cost = particle(i).Best.Cost;

                    GlobalBest = particle(i).Best;

                end

            end

            BestCost = zeros(MaxIt,1);
            nfe = zeros(MaxIt,1);

            %% PSO Main Loop
            for it=1:MaxIt

                for i=1:nPop

                    % x Part

                    % Update Velocity
                    particle(i).Velocity.x = w*particle(i).Velocity.x ...
                        + c1*rand(VarSize).*(particle(i).Best.Position.x - particle(i).Position.x)...
                        + c2*rand(VarSize).*(GlobalBest.Position.x - particle(i).Position.x);

                    % Apply Velocity Limits
                    particle(i).Velocity.x = max(particle(i).Velocity.x,VelMin.x);
                    particle(i).Velocity.x = min(particle(i).Velocity.x,VelMax.x);

                    % Update Position
                    particle(i).Position.x = particle(i).Position.x + particle(i).Velocity.x;

                    % Velocity Mirror Effect
                    IsOutside = (particle(i).Position.x<VarMin.x | particle(i).Position.x > VarMax.x);
                    particle(i).Velocity.x(IsOutside) = -particle(i).Velocity.x(IsOutside);

                    % Apply Position Limits
                    particle(i).Position.x = max(particle(i).Position.x,VarMin.x);
                    particle(i).Position.x = min(particle(i).Position.x,VarMax.x);

                    % y Part

                    % Update Velocity
                    particle(i).Velocity.y = w*particle(i).Velocity.y ...
                        + c1*rand(VarSize).*(particle(i).Best.Position.y - particle(i).Position.y)...
                        + c2*rand(VarSize).*(GlobalBest.Position.y - particle(i).Position.y);

                    % Apply Velocity Limits
                    particle(i).Velocity.y = max(particle(i).Velocity.y,VelMin.y);
                    particle(i).Velocity.y = min(particle(i).Velocity.y,VelMax.y);

                    % Update Position
                    particle(i).Position.y = particle(i).Position.y + particle(i).Velocity.y;

                    % Velocity Mirror Effect
                    IsOutside = (particle(i).Position.y<VarMin.y | particle(i).Position.y > VarMax.y);
                    particle(i).Velocity.y(IsOutside) = -particle(i).Velocity.y(IsOutside);

                    % Apply Position Limits
                    particle(i).Position.y = max(particle(i).Position.y,VarMin.y);
                    particle(i).Position.y = min(particle(i).Position.y,VarMax.y);


                    % Evaluation
                    [particle(i).Cost ,particle(i).Out] = CostFunction(particle(i).Position);

                    % Update Personal Best
                    if particle(i).Cost < particle(i).Best.Cost

                        particle(i).Best.Position = particle(i).Position;
                        particle(i).Best.Cost = particle(i).Cost;
                        particle(i).Best.Out = particle(i).Out;

                        % Update Gloabl Best
                        if particle(i).Best.Cost < GlobalBest.Cost

                            GlobalBest = particle(i).Best;

                        end

                    end

                end

                BestCost(it) = GlobalBest.Cost;
                nfe(it) = NFE;

                % Display Results on Comman Line
                if GlobalBest.Out.IsFeasible
                    Flag = ' *';
                else
                    Flag = [', Violation = ' num2str(GlobalBest.Out.MeanViolation)];
                end
                disp(['Iteration ' num2str(it) ': NFE = ' num2str(nfe(it)) ', Best Cost = ' num2str(BestCost(it)) Flag]);

                % Save Results in mat file
                app.saveData = [app.saveData; nfe(it) BestCost(it)];

                % Plot Solution
                app.PlotSolution(GlobalBest.Out,model)
                app.ItEditField.Value = num2str(it);
                app.NFEEditField.Value = num2str(NFE);
                app.Best_CostEditField.Value = num2str(GlobalBest.Cost);

                w = w*wdamp;
                app.StartButton.Enable = "off";

            end

            app.SaveDataButton.Enable = 'on';

        end

        % Button pushed function: CreateStartEndandObstacleButton
        function CreateStartEndandObstacleButtonPushed(app, event)
            model = app.CreateModel();
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,model.xs,model.ys,'bs','MarkerSize',16,'MarkerFaceColor','y')
            plot(app.UIAxes,model.xt,model.yt,'kp','MarkerSize',20,'MarkerFaceColor','g')
            theta = linspace(0,2*pi,100);
            fill(app.UIAxes,model.xc + model.r * cos(theta), model.yc + model.r * sin(theta),'m')
            hold(app.UIAxes,'off')
            grid(app.UIAxes,"on")
            axis(app.UIAxes, 'padded');
            app.StartButton.Enable = "on";
            app.SaveDataButton.Enable = 'off';
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            data = mat2cell(app.saveData,size(app.saveData,1),size(app.saveData,2));
            uisave;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            colormap(app.UIAxes, 'white')
            app.StartandendcoordinatesPanel = uipanel(app.UIFigure);
            app.StartandendcoordinatesPanel.Title = 'Start and end coordinates';
            app.X_StartEditFieldLabel = uilabel(app.StartandendcoordinatesPanel);
            app.X_StartEditFieldLabel.Text = 'X_Start';
            app.X_StartEditField = uieditfield(app.StartandendcoordinatesPanel, 'text');
            app.X_StartEditField.Value = '0';
            app.Y_StartEditFieldLabel = uilabel(app.StartandendcoordinatesPanel);
            app.Y_StartEditFieldLabel.Text = 'Y_Start';
            app.Y_StartEditField = uieditfield(app.StartandendcoordinatesPanel, 'text');
            app.Y_StartEditField.Value = '0';
            app.X_EndEditFieldLabel = uilabel(app.StartandendcoordinatesPanel);
            app.X_EndEditFieldLabel.Text = 'X_End';
            app.X_EndEditField = uieditfield(app.StartandendcoordinatesPanel, 'text');
            app.X_EndEditField.Value = '10';
            app.Y_EndEditFieldLabel = uilabel(app.StartandendcoordinatesPanel);
            app.Y_EndEditFieldLabel.Text = 'Y_End';
            app.Y_EndEditField = uieditfield(app.StartandendcoordinatesPanel, 'text');
            app.Y_EndEditField.Value = '10';
            app.ObstaclecoordinatesPanel = uipanel(app.UIFigure);
            app.ObstaclecoordinatesPanel.Title = 'Obstacle coordinates';
            app.X_CenterEditFieldLabel = uilabel(app.ObstaclecoordinatesPanel);
            app.X_CenterEditFieldLabel.Text = 'X_Center';
            app.X_CenterEditField = uieditfield(app.ObstaclecoordinatesPanel, 'text');
            app.X_CenterEditField.Value = '3';
            app.Y_CenterEditFieldLabel = uilabel(app.ObstaclecoordinatesPanel);
            app.Y_CenterEditFieldLabel.Text = 'Y_Center';
            app.Y_CenterEditField = uieditfield(app.ObstaclecoordinatesPanel, 'text');
            app.Y_CenterEditField.Value = '3';
            app.RadiusEditFieldLabel = uilabel(app.ObstaclecoordinatesPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.ObstaclecoordinatesPanel, 'text');
            app.RadiusEditField.Value = '3';
            app.CreateStartEndandObstacleButton = uibutton(app.UIFigure, 'push');
            app.CreateStartEndandObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @CreateStartEndandObstacleButtonPushed, true);
            app.CreateStartEndandObstacleButton.Text = 'Create Start, End and Obstacle';
            app.PSOParametersPanel = uipanel(app.UIFigure);
            app.PSOParametersPanel.Title = 'PSO Parameters';
            app.MaximumIterationLabel = uilabel(app.PSOParametersPanel);
            app.MaximumIterationLabel.Text = 'Maximum Iteration';
            app.MaximumIterationEditField = uieditfield(app.PSOParametersPanel, 'text');
            app.MaximumIterationEditField.Value = '200';
            app.NumberofPopulationLabel = uilabel(app.PSOParametersPanel);
            app.NumberofPopulationLabel.Text = 'Number of Population';
            app.NumberofPopulationEditField = uieditfield(app.PSOParametersPanel, 'text');
            app.NumberofPopulationEditField.Value = '100';
            app.InertiaWeightEditFieldLabel = uilabel(app.PSOParametersPanel);
            app.InertiaWeightEditFieldLabel.Text = 'Inertia Weight';
            app.InertiaWeightEditField = uieditfield(app.PSOParametersPanel, 'text');
            app.InertiaWeightEditField.Value = '1';
            app.InertiaWeightDampingRatioEditFieldLabel = uilabel(app.PSOParametersPanel);
            app.InertiaWeightDampingRatioEditFieldLabel.Text = 'Inertia Weight Damping Ratio';
            app.InertiaWeightDampingRatioEditField = uieditfield(app.PSOParametersPanel, 'text');
            app.InertiaWeightDampingRatioEditField.Value = '0.99';
            app.C1SliderLabel = uilabel(app.PSOParametersPanel);
            app.C1SliderLabel.Text = 'C1';
            app.C1Slider = uislider(app.PSOParametersPanel);
            app.C1Slider.Limits = [0 4];
            app.C1Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.C1Slider.Value = 2;
            app.C2SliderLabel = uilabel(app.PSOParametersPanel);
            app.C2SliderLabel.Text = 'C2';
            app.C2Slider = uislider(app.PSOParametersPanel);
            app.C2Slider.Limits = [0 4];
            app.C2Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4];
            app.C2Slider.Value = 2;
            app.ChartcoordinatesandnumberofsplinesPanel = uipanel(app.UIFigure);
            app.ChartcoordinatesandnumberofsplinesPanel.Title = 'Chart coordinates and number of splines';
            app.X_minEditFieldLabel = uilabel(app.ChartcoordinatesandnumberofsplinesPanel);
            app.X_minEditFieldLabel.Text = 'X_min';
            app.X_minEditField = uieditfield(app.ChartcoordinatesandnumberofsplinesPanel, 'text');
            app.X_minEditField.Value = '-10';
            app.Y_minEditFieldLabel = uilabel(app.ChartcoordinatesandnumberofsplinesPanel);
            app.Y_minEditFieldLabel.Text = 'Y_min';
            app.Y_minEditField = uieditfield(app.ChartcoordinatesandnumberofsplinesPanel, 'text');
            app.Y_minEditField.Value = '-10';
            app.X_maxEditFieldLabel = uilabel(app.ChartcoordinatesandnumberofsplinesPanel);
            app.X_maxEditFieldLabel.Text = 'X_max';
            app.X_maxEditField = uieditfield(app.ChartcoordinatesandnumberofsplinesPanel, 'text');
            app.X_maxEditField.Value = '10';
            app.Y_maxEditFieldLabel = uilabel(app.ChartcoordinatesandnumberofsplinesPanel);
            app.Y_maxEditFieldLabel.Text = 'Y_max';
            app.Y_maxEditField = uieditfield(app.ChartcoordinatesandnumberofsplinesPanel, 'text');
            app.Y_maxEditField.Value = '10';
            app.NumberofSplinesEditFieldLabel = uilabel(app.ChartcoordinatesandnumberofsplinesPanel);
            app.NumberofSplinesEditFieldLabel.Text = 'Number of Splines';
            app.NumberofSplinesEditField = uieditfield(app.ChartcoordinatesandnumberofsplinesPanel, 'text');
            app.NumberofSplinesEditField.Value = '3';
            app.ReportPanel = uipanel(app.UIFigure);
            app.ReportPanel.Title = 'Report';
            app.ItEditFieldLabel = uilabel(app.ReportPanel);
            app.ItEditFieldLabel.Text = 'It';
            app.ItEditField = uieditfield(app.ReportPanel, 'text');
            app.ItEditField.Editable = 'off';
            app.NFEEditFieldLabel = uilabel(app.ReportPanel);
            app.NFEEditFieldLabel.Text = 'NFE';
            app.NFEEditField = uieditfield(app.ReportPanel, 'text');
            app.NFEEditField.Editable = 'off';
            app.Best_CostEditFieldLabel = uilabel(app.ReportPanel);
            app.Best_CostEditFieldLabel.Text = 'Best_Cost';
            app.Best_CostEditField = uieditfield(app.ReportPanel, 'text');
            app.Best_CostEditField.Editable = 'off';
            app.SaveDataButton = uibutton(app.ReportPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Text = 'Save Data';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'http://tohid.com.tr';
            app.Hyperlink.Text = 'Developed by Tohid';
            app.RobotPathPlanningVersion1Label = uilabel(app.UIFigure);
            app.RobotPathPlanningVersion1Label.Text = 'Robot Path Planning(Version 1)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robot_Path_Planning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end